classdef TLineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        CharcteristicImpedanceohmsEditFieldLabel  matlab.ui.control.Label
        CharcteristicImpedanceohmsEditField  matlab.ui.control.NumericEditField
        TransmissionLineLengthmEditFieldLabel  matlab.ui.control.Label
        TransmissionLineLengthmEditField  matlab.ui.control.NumericEditField
        PhaseVelocityx108msEditFieldLabel  matlab.ui.control.Label
        PhaseVelocityx108msEditField   matlab.ui.control.NumericEditField
        SourceVoltageVEditFieldLabel   matlab.ui.control.Label
        SourceVoltageVEditField        matlab.ui.control.NumericEditField
        SourceResistanceohmsEditFieldLabel  matlab.ui.control.Label
        SourceResistanceohmsEditField  matlab.ui.control.NumericEditField
        SourceReactanceohmsEditFieldLabel  matlab.ui.control.Label
        SourceReactanceohmsEditField   matlab.ui.control.NumericEditField
        LoadResistanceohmsEditFieldLabel  matlab.ui.control.Label
        LoadResistanceohmsEditField    matlab.ui.control.NumericEditField
        LoadReactanceohmsEditFieldLabel  matlab.ui.control.Label
        LoadReactanceohmsEditField     matlab.ui.control.NumericEditField
        ControlPanel                   matlab.ui.container.Panel
        Slider                         matlab.ui.control.Slider
        SweepVarSwitchLabel            matlab.ui.control.Label
        SweepVarSwitch                 matlab.ui.control.Switch
        SliderLabel                    matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        InductancenHEditFieldLabel     matlab.ui.control.Label
        InductancenHEditField          matlab.ui.control.NumericEditField
        CapacitancepFEditFieldLabel    matlab.ui.control.Label
        CapacitancepFEditField         matlab.ui.control.NumericEditField
        PropagationTimensEditFieldLabel  matlab.ui.control.Label
        PropagationTimensEditField     matlab.ui.control.NumericEditField
        LoadReflectionCoefficientEditFieldLabel  matlab.ui.control.Label
        LoadReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        SourceReflectionCoefficientEditFieldLabel  matlab.ui.control.Label
        SourceReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        SteadyStateVoltageVEditFieldLabel  matlab.ui.control.Label
        SteadyStateVoltageVEditField   matlab.ui.control.NumericEditField
        SteadyStateCurrentmAEditFieldLabel  matlab.ui.control.Label
        SteadyStateCurrentmAEditField  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CharacteristicImpedance = 100
        PhaseVelocity = 3e8;
        
        LineLength = 5;
        
        SourceVoltage = 12;
        SourceResistance = 50;
        LoadResistance = 200;
        
        InductancenH = 0;
        CapacitancepF = 0;
        
        PropagationTimeNs = 0;
        
        LoadReflectionCoefficient = 0;
        SourceReflectionCoefficient = 0;
        
        SteadyStateVoltageV = 0;
        SteadyStateCurrentmA = 0;
    end
    
    methods (Access = private)
        
        function updateCircuitElementValues(app)
            try
                [app.InductancenH, app.CapacitancepF] = getCircuitElementValues(app.CharacteristicImpedance, app.PhaseVelocity);
                app.InductancenHEditField.Value = app.InductancenH;
                app.CapacitancepFEditField.Value = app.CapacitancepF; 
            catch ME
                disp(ME.message);
            end
        end
        
        function updateSteadyStateMeasurements(app)
            try
                app.SteadyStateVoltageV = getSteadyStateVoltage(app.SourceVoltage, app.SourceResistance, app.LoadResistance);
                app.SteadyStateCurrentmA = getSteadyStateCurrent(app.SourceVoltage, app.SourceResistance, app.LoadResistance);
                
                app.SteadyStateVoltageVEditField.Value = app.SteadyStateVoltageV;
                app.SteadyStateCurrentmAEditField.Value = app.SteadyStateCurrentmA;   
            catch ME
                disp(ME.message);
            end
        end
        
        
        function updateLoadReflectionCoefficient(app)
            try
                app.LoadReflectionCoefficient = getReflectionCoefficient(app.CharacteristicImpedance, app.LoadResistance);
                app.LoadReflectionCoefficientEditField.Value = app.LoadReflectionCoefficient;
            catch ME
                disp(ME.message);
            end
        end
        
        function updateSourceReflectionCoefficient(app)
            try
                app.SourceReflectionCoefficient = getReflectionCoefficient(app.CharacteristicImpedance, app.SourceResistance);
                app.SourceReflectionCoefficientEditField.Value = app.SourceReflectionCoefficient;
            catch ME
                disp(ME.message);
            end
        end
        
        function updatePropagationTime(app)
            app.PropagationTimeNs = getPropagationTime(app.LineLength, app.PhaseVelocity);
            app.PropagationTimensEditField.Value = app.PropagationTimeNs;
        end
        
        function updateSlider(app)
            if app.SweepVarSwitch.Value == "Sweep Time"
                app.Slider.Limits = [0 10*app.PropagationTimeNs];
                app.SliderLabel.Text = "Time";
            else
                app.Slider.Limits = [0 app.LineLength];
                app.SliderLabel.Text = "Position";
            end
            
            app.Slider.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateCircuitElementValues(app);
            updateSteadyStateMeasurements(app);
            updateLoadReflectionCoefficient(app);
            updateSourceReflectionCoefficient(app);
            updatePropagationTime(app);
            updateSlider(app);
        end

        % Value changed function: 
        % CharcteristicImpedanceohmsEditField
        function CharcteristicImpedanceohmsEditFieldValueChanged(app, event)
            app.CharacteristicImpedance = app.CharcteristicImpedanceohmsEditField.Value;
            updateCircuitElementValues(app);
        end

        % Value changed function: PhaseVelocityx108msEditField
        function PhaseVelocityx108msEditFieldValueChanged(app, event)
            app.PhaseVelocity = app.PhaseVelocityx108msEditField.Value * 10^8;
            updateCircuitElementValues(app);
            updateSlider(app);
        end

        % Value changed function: SourceVoltageVEditField
        function SourceVoltageVEditFieldValueChanged(app, event)
            app.SourceVoltage = app.SourceVoltageVEditField.Value;
            updateSteadyStateMeasurements(app);
        end

        % Value changed function: SourceResistanceohmsEditField
        function SourceResistanceohmsEditFieldValueChanged(app, event)
            app.SourceResistance = app.SourceResistanceohmsEditField.Value;
            updateSteadyStateMeasurements(app);
            updateSourceReflectionCoefficient(app);
        end

        % Value changed function: LoadResistanceohmsEditField
        function LoadResistanceohmsEditFieldValueChanged(app, event)
            app.LoadResistance = app.LoadResistanceohmsEditField.Value;
            updateSteadyStateMeasurements(app);
            updateLoadReflectionCoefficient(app);
        end

        % Value changed function: TransmissionLineLengthmEditField
        function TransmissionLineLengthmEditFieldValueChanged(app, event)
            app.LineLength = app.TransmissionLineLengthmEditField.Value;
            updatePropagationTime(app);
            updateSlider(app);
        end

        % Value changed function: SweepVarSwitch
        function SweepVarSwitchValueChanged(app, event)
            updateSlider(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.CharcteristicImpedanceohmsEditFieldLabel = uilabel(app.InputPanel);
            app.CharcteristicImpedanceohmsEditFieldLabel.Text = 'Charcteristic Impedance (ohms)';
            app.CharcteristicImpedanceohmsEditField = uieditfield(app.InputPanel, 'numeric');
            app.CharcteristicImpedanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @CharcteristicImpedanceohmsEditFieldValueChanged, true);
            app.CharcteristicImpedanceohmsEditField.Value = 100;
            app.TransmissionLineLengthmEditFieldLabel = uilabel(app.InputPanel);
            app.TransmissionLineLengthmEditFieldLabel.Text = 'Transmission Line Length (m)';
            app.TransmissionLineLengthmEditField = uieditfield(app.InputPanel, 'numeric');
            app.TransmissionLineLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @TransmissionLineLengthmEditFieldValueChanged, true);
            app.TransmissionLineLengthmEditField.Value = 5;
            app.PhaseVelocityx108msEditFieldLabel = uilabel(app.InputPanel);
            app.PhaseVelocityx108msEditFieldLabel.Text = 'Phase Velocity (x 10^8) (m/s)';
            app.PhaseVelocityx108msEditField = uieditfield(app.InputPanel, 'numeric');
            app.PhaseVelocityx108msEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseVelocityx108msEditFieldValueChanged, true);
            app.PhaseVelocityx108msEditField.Value = 3;
            app.SourceVoltageVEditFieldLabel = uilabel(app.InputPanel);
            app.SourceVoltageVEditFieldLabel.Text = 'Source Voltage (V)';
            app.SourceVoltageVEditField = uieditfield(app.InputPanel, 'numeric');
            app.SourceVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @SourceVoltageVEditFieldValueChanged, true);
            app.SourceVoltageVEditField.Value = 12;
            app.SourceResistanceohmsEditFieldLabel = uilabel(app.InputPanel);
            app.SourceResistanceohmsEditFieldLabel.Text = 'Source Resistance (ohms)';
            app.SourceResistanceohmsEditField = uieditfield(app.InputPanel, 'numeric');
            app.SourceResistanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @SourceResistanceohmsEditFieldValueChanged, true);
            app.SourceResistanceohmsEditField.Value = 50;
            app.SourceReactanceohmsEditFieldLabel = uilabel(app.InputPanel);
            app.SourceReactanceohmsEditFieldLabel.Text = 'Source Reactance (ohms)';
            app.SourceReactanceohmsEditField = uieditfield(app.InputPanel, 'numeric');
            app.LoadResistanceohmsEditFieldLabel = uilabel(app.InputPanel);
            app.LoadResistanceohmsEditFieldLabel.Text = 'Load Resistance (ohms)';
            app.LoadResistanceohmsEditField = uieditfield(app.InputPanel, 'numeric');
            app.LoadResistanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @LoadResistanceohmsEditFieldValueChanged, true);
            app.LoadResistanceohmsEditField.Value = 200;
            app.LoadReactanceohmsEditFieldLabel = uilabel(app.InputPanel);
            app.LoadReactanceohmsEditFieldLabel.Text = 'Load Reactance (ohms)';
            app.LoadReactanceohmsEditField = uieditfield(app.InputPanel, 'numeric');
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.Slider = uislider(app.ControlPanel);
            app.SweepVarSwitchLabel = uilabel(app.ControlPanel);
            app.SweepVarSwitchLabel.Text = 'Sweep Var';
            app.SweepVarSwitch = uiswitch(app.ControlPanel, 'slider');
            app.SweepVarSwitch.Items = {'Sweep Time', 'Sweep Position'};
            app.SweepVarSwitch.ValueChangedFcn = createCallbackFcn(app, @SweepVarSwitchValueChanged, true);
            app.SweepVarSwitch.Value = 'Sweep Time';
            app.SliderLabel = uilabel(app.ControlPanel);
            app.SliderLabel.Text = 'Slider';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output Panel';
            app.InductancenHEditFieldLabel = uilabel(app.OutputPanel);
            app.InductancenHEditFieldLabel.Text = 'Inductance (nH)';
            app.InductancenHEditField = uieditfield(app.OutputPanel, 'numeric');
            app.CapacitancepFEditFieldLabel = uilabel(app.OutputPanel);
            app.CapacitancepFEditFieldLabel.Text = 'Capacitance (pF)';
            app.CapacitancepFEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PropagationTimensEditFieldLabel = uilabel(app.OutputPanel);
            app.PropagationTimensEditFieldLabel.Text = 'Propagation Time (ns)';
            app.PropagationTimensEditField = uieditfield(app.OutputPanel, 'numeric');
            app.LoadReflectionCoefficientEditFieldLabel = uilabel(app.OutputPanel);
            app.LoadReflectionCoefficientEditFieldLabel.Text = 'Load Reflection Coefficient';
            app.LoadReflectionCoefficientEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SourceReflectionCoefficientEditFieldLabel = uilabel(app.OutputPanel);
            app.SourceReflectionCoefficientEditFieldLabel.Text = 'Source Reflection Coefficient';
            app.SourceReflectionCoefficientEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SteadyStateVoltageVEditFieldLabel = uilabel(app.OutputPanel);
            app.SteadyStateVoltageVEditFieldLabel.Text = 'Steady-State Voltage (V)';
            app.SteadyStateVoltageVEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SteadyStateCurrentmAEditFieldLabel = uilabel(app.OutputPanel);
            app.SteadyStateCurrentmAEditFieldLabel.Text = 'Steady State Current (mA)';
            app.SteadyStateCurrentmAEditField = uieditfield(app.OutputPanel, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLineApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end